classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CreateMaterialButton  matlab.ui.control.Button
        DeleteMaterialButton  matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        E1EditFieldLabel      matlab.ui.control.Label
        E1                    matlab.ui.control.NumericEditField
        NameEditFieldLabel    matlab.ui.control.Label
        matname               matlab.ui.control.EditField
        E2EditFieldLabel      matlab.ui.control.Label
        E2                    matlab.ui.control.NumericEditField
        NU12EditFieldLabel    matlab.ui.control.Label
        NU12                  matlab.ui.control.NumericEditField
        G12EditFieldLabel     matlab.ui.control.Label
        G12                   matlab.ui.control.NumericEditField
        R1EditFieldLabel      matlab.ui.control.Label
        R1                    matlab.ui.control.NumericEditField
        R2EditFieldLabel      matlab.ui.control.Label
        R2                    matlab.ui.control.NumericEditField
        R12EditFieldLabel     matlab.ui.control.Label
        R12                   matlab.ui.control.NumericEditField
        Titel                 matlab.ui.control.Label
        CreatePlyButton       matlab.ui.control.Button
        Titel_2               matlab.ui.control.Label
        DeletePlyButton       matlab.ui.control.Button
        UITable_2             matlab.ui.control.Table
    end

    methods (Access = private)

        % Callback function: CreateMaterialButton, UITable
        function CreateMaterialButtonPushed(app, event)
            data = app.UITable.Data;
            if isempty(data) == true;
                data = {};
            end
            data(end+1,:) = {app.matname.Value, app.E1.Value, app.E2.Value, app.NU12.Value, app.G12.Value, app.R1.Value, app.R2.Value, app.R12.Value};
            set(app.UITable,'Data',data);
        end

        % Button pushed function: DeleteMaterialButton
        function DeleteMaterialButtonPushed(app, event)
            matcount = size(app.UITable.Data,1)
            app.UITable.Data = app.UITable.Data(1:matcount-1,:);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CreateMaterialButton = uibutton(app.UIFigure, 'push');
            app.CreateMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMaterialButtonPushed, true);
            app.CreateMaterialButton.Text = 'Create Material';
            app.DeleteMaterialButton = uibutton(app.UIFigure, 'push');
            app.DeleteMaterialButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteMaterialButtonPushed, true);
            app.DeleteMaterialButton.Text = 'Delete Material';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Name'; 'E1'; 'E2'; 'NU12'; 'G12'; 'R1'; 'R2'; 'R12'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @CreateMaterialButtonPushed, true);
            app.E1EditFieldLabel = uilabel(app.UIFigure);
            app.E1EditFieldLabel.Text = 'E1';
            app.E1 = uieditfield(app.UIFigure, 'numeric');
            app.E1.Value = 210000;
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.matname = uieditfield(app.UIFigure, 'text');
            app.matname.Value = 'Steel';
            app.E2EditFieldLabel = uilabel(app.UIFigure);
            app.E2EditFieldLabel.Text = 'E2';
            app.E2 = uieditfield(app.UIFigure, 'numeric');
            app.E2.Value = 210000;
            app.NU12EditFieldLabel = uilabel(app.UIFigure);
            app.NU12EditFieldLabel.Text = 'NU12';
            app.NU12 = uieditfield(app.UIFigure, 'numeric');
            app.NU12.Value = 0.3;
            app.G12EditFieldLabel = uilabel(app.UIFigure);
            app.G12EditFieldLabel.Text = 'G12';
            app.G12 = uieditfield(app.UIFigure, 'numeric');
            app.G12.Value = 1000;
            app.R1EditFieldLabel = uilabel(app.UIFigure);
            app.R1EditFieldLabel.Text = 'R1';
            app.R1 = uieditfield(app.UIFigure, 'numeric');
            app.R1.Value = 500;
            app.R2EditFieldLabel = uilabel(app.UIFigure);
            app.R2EditFieldLabel.Text = 'R2';
            app.R2 = uieditfield(app.UIFigure, 'numeric');
            app.R2.Value = 500;
            app.R12EditFieldLabel = uilabel(app.UIFigure);
            app.R12EditFieldLabel.Text = 'R12';
            app.R12 = uieditfield(app.UIFigure, 'numeric');
            app.R12.Value = 500;
            app.Titel = uilabel(app.UIFigure);
            app.Titel.Text = 'Materials';
            app.CreatePlyButton = uibutton(app.UIFigure, 'push');
            app.CreatePlyButton.Text = 'Create Ply';
            app.Titel_2 = uilabel(app.UIFigure);
            app.Titel_2.Text = 'Laminate';
            app.DeletePlyButton = uibutton(app.UIFigure, 'push');
            app.DeletePlyButton.Text = 'Delete Ply';
            app.UITable_2 = uitable(app.UIFigure);
            app.UITable_2.ColumnName = {''; 'E1'; 'E2'; 'NU12'; 'G12'; 'R1'; 'R2'; 'R12'};
            app.UITable_2.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = test

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end